classdef ScanTechApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ScanTechFigure                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        FileTab                         matlab.ui.container.Tab
        ValidateTable                   matlab.ui.control.Table
        ValidationPanel                 matlab.ui.container.Panel
        LeftValidateButton              matlab.ui.control.Button
        RightValidateButton             matlab.ui.control.Button
        AcceptAnswersButton             matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ProcessDataPanel                matlab.ui.container.Panel
        ValidateAnswersButton           matlab.ui.control.Button
        ProcessAnswerSheetsButton       matlab.ui.control.Button
        GenerateAnswerStatisticsButton  matlab.ui.control.Button
        InputDataPanel                  matlab.ui.container.Panel
        LoadSessionButton               matlab.ui.control.Button
        UploadAnswerSheetsButton        matlab.ui.control.Button
        NewSessionButton                matlab.ui.control.Button
        FileListPanel                   matlab.ui.container.Panel
        RepairFilesButton               matlab.ui.control.Button
        DeleteFileButton                matlab.ui.control.Button
        FileListTree                    matlab.ui.container.Tree
        ValidateAxes                    matlab.ui.control.UIAxes
        ImageAxes                       matlab.ui.control.UIAxes
    end

    % TO DO: Generate score reports to make it easier to manually validate
    % values. Create code and UI for manual validation. Create method of
    % copying to clipboard. Create code to generate answer statistics
    
    properties (Access = private)
        filepath = pwd;                    % Path with app inside of it, used to recommend default directory
        currentTestDirectory               % Full directory for current test
        folderNode;                        % Storage nodes for folders
        fileNode;                          % Storage nodes for files
        missingFile;                       % Cell with missing files for directory repair
        plotImage;                         % Name of plot to show .png files as images
        plotValid;                         % Name of plot to show validation images
        answerNetwork;                     % Neural network for answer bubbles
        numberNetwork;                     % Neural network for number bubbles
        invalidStudentDir;                 % List of student sheets to validate
        currentValidInd;                   % Current student sheet displayed during validation
    end
    
    methods (Access = private)

        function restorePath(app)
            % Restores default path and adds function subfolder
            restoredefaultpath
            addpath(genpath(strcat(app.filepath,'\ImageProcesses')));
        end

        function resetFileUI(app)
            % Resets the UI for the file tab so there isnt overlapping
            
            app.UITable.Visible = 'off';
            app.ImageAxes.Visible = 'off';
            app.ValidateTable.Visible = 'off';
            app.ValidateAxes.Visible = 'off';

            cla(app.ImageAxes);
            delete(app.plotImage);
            cla(app.ValidateAxes);
            delete(app.plotValid);

            app.LeftValidateButton.Enable = 'off';
            app.RightValidateButton.Enable = 'off';
            app.AcceptAnswersButton.Enable = 'off';

        end

        function createDirectory(app)
            % Creates folder structure and required files for exams
            
            % Make folders
            mkdir(app.currentTestDirectory)
            mkdir(strcat(app.currentTestDirectory, '\Input'))
            mkdir(strcat(app.currentTestDirectory, '\Output'))
            mkdir(strcat(app.currentTestDirectory, '\Keys'))
            mkdir(strcat(app.currentTestDirectory, '\Questions'))
            mkdir(strcat(app.currentTestDirectory, '\ScoreReports'))

            % Store classlist data
            tableData = {[], []};
            save(strcat(app.currentTestDirectory, '\Keys\classlist.mat'),'tableData');

            % Store answer key data
            tableData = {'diag', []; [], []};
            save(strcat(app.currentTestDirectory, '\Keys\answerKey.mat'),'tableData');

            % Store answer key data
            tableData = {[]};
            save(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'),'tableData');

            % Store validation data
            tableData = {[]};
            save(strcat(app.currentTestDirectory, '\Output\verifyOutput.mat'),'tableData');

            % Store valid classlist with percentages
            tableData = {[]};
            save(strcat(app.currentTestDirectory, '\Output\validClasslist.mat'),'tableData');

            % Store invalid classlist with percentages
            tableData = {[]};
            save(strcat(app.currentTestDirectory, '\Output\invalidClasslist.mat'),'tableData');

            % Store answer statistics
            tableData = {[]};
            save(strcat(app.currentTestDirectory, '\Output\answerStatistics.mat'),'tableData');

        end

        function fileListUpdate(app)
            % Creates a tree of selectable files from structure

            % Clear current filelist
            app.FileListTree.Children.delete;
            app.folderNode = [];
            app.fileNode = [];
            
            % Read current directory for all folders
            dataDir = app.currentTestDirectory;
            filetype = '';
            dirInfo = dir(strcat(dataDir,filetype));
            dirInfo = struct2table(dirInfo);
            folders = dirInfo(dirInfo.isdir,1);
            folders = folders(~matches(folders.name,[".",".."]),:);

            % Parse through folders and create nodes for them
            ind = 0;
            for i = 1:size(folders,1)
                folderName = string(folders.name(i));
                app.folderNode{i} = uitreenode(app.FileListTree);
                app.folderNode{i}.Text = folderName;

                % Read current folder for every file
                dirInfo = dir(strcat(dataDir,'\',folderName,filetype));
                dirInfo = struct2table(dirInfo);
                files = dirInfo(~dirInfo.isdir,1);
                
                % Parse through files and create nodes for them
                for j = 1:size(files,1)
                    ind = ind+1;
                    app.fileNode{ind} = uitreenode(app.folderNode{i});
                    app.fileNode{ind}.Text = string(files.name(j));
                end
            end

            if max(matches(folders.name,"Input"))
                app.UploadAnswerSheetsButton.Enable = 'on';
                app.ProcessAnswerSheetsButton.Enable = 'on';
                app.ValidateAnswersButton.Enable = 'on';
                app.GenerateAnswerStatisticsButton.Enable = 'on';
                app.RepairFilesButton.Enable = 'on';
                app.DeleteFileButton.Enable = 'on';
            else
                app.UploadAnswerSheetsButton.Enable = 'off';
                app.ProcessAnswerSheetsButton.Enable = 'off';
                app.ValidateAnswersButton.Enable = 'off';
                app.GenerateAnswerStatisticsButton.Enable = 'off';
                app.RepairFilesButton.Enable = 'off';
                app.DeleteFileButton.Enable = 'off';
            end

        end
        
        function directory = fileListDirectory(app,node)
            % Pull directory from file list by checking parents of nodes

            % Get initial node
            directory = node.Text;

            % While the parents are treenodes, add them to directory
            while matches(node.Parent.Type,'uitreenode')
                node = node.Parent;
                directory = strcat(node.Text,'\',directory);
            end

            % Add base path to directory
            directory = strcat(app.currentTestDirectory,'\',directory);
        end

        function fileListRepair(app)
            % Function checks for valid files to repair

            % Check if classlist.mat exists and generate new one if gone
            classlistFile = fullfile(app.currentTestDirectory,'\Keys\classlist.mat');
            if ~exist(classlistFile)
                tableData = {[], []};
                save(strcat(app.currentTestDirectory, '\Keys\classlist.mat'),'tableData');
            end

            % Check if answerKey.mat exists and generate new one if gone
            answerKeyFile = fullfile(app.currentTestDirectory,'\Keys\answerKey.mat');
            if ~exist(answerKeyFile)
                % Store answer key data
                tableData = {'diag', []; [], []};
                save(strcat(app.currentTestDirectory, '\Keys\answerKey.mat'),'tableData');
            end

            % Check if studentOutput.mat exists and generate new one if gone
            outputFile = fullfile(app.currentTestDirectory,'\Output\studentOutput.mat');
            if ~exist(outputFile)
                % Store answer key data
                tableData = {[]};
                save(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'),'tableData');
            end

            % Check if verifyOutput.mat exists and generate new one if gone
            outputFile = fullfile(app.currentTestDirectory,'\Output\verifyOutput.mat');
            if ~exist(outputFile)
                % Store answer key data
                tableData = {[]};
                save(strcat(app.currentTestDirectory, '\Output\verifyOutput.mat'),'tableData');
            end

            % Check if validClasslist.mat exists and generate new one if gone
            outputFile = fullfile(app.currentTestDirectory,'\Output\validClasslist.mat');
            if ~exist(outputFile)
                % Store answer key data
                tableData = {[]};
                save(strcat(app.currentTestDirectory, '\Output\validClasslist.mat'),'tableData');
            end

            % Check if invalidClasslist.mat exists and generate new one if gone
            outputFile = fullfile(app.currentTestDirectory,'\Output\invalidClasslist.mat');
            if ~exist(outputFile)
                % Store answer key data
                tableData = {[]};
                save(strcat(app.currentTestDirectory, '\Output\invalidClasslist.mat'),'tableData');
            end

            % Check if answerStatistics.mat exists and generate new one if gone
            outputFile = fullfile(app.currentTestDirectory,'\Output\answerStatistics.mat');
            if ~exist(outputFile)
                % Store answer key data
                tableData = {[]};
                save(strcat(app.currentTestDirectory, '\Output\answerStatistics.mat'),'tableData');
            end
        end

        function tablePasteData(app)           
            % Reads data from clipboard and pastes them on the current table
            
            % Pull data from first selected cell
            selectedCell = app.UITable.Selection;
            if isempty(selectedCell)
                selectedCell = [1,1];
            else
                selectedCell = selectedCell(1,:);
            end
            tableData = app.UITable.Data;
            
            % Split copied data vertically by enters and horizontally by tabs
            pasteData = clipboard('paste');
            pasteData = splitlines(pasteData);
            pasteData(cellfun(@isempty,pasteData)) = [];
            pasteData = split(pasteData,sprintf('\t'));

            % Table sizes
            [tableR, tableC] = size(tableData);
            [pasteR, pasteC] = size(pasteData);
            
            % Initialize cell to store data to
            storeData = cell(max(tableR,selectedCell(1)+pasteR-1),max(tableC,selectedCell(2)+pasteC-1));
            storeData(1:tableR,1:tableC) = tableData;

            % Store data
            storeData(selectedCell(1):selectedCell(1)+pasteR-1,selectedCell(2):selectedCell(2)+pasteC-1) = pasteData;

            % Store data to table
            app.UITable.Data=storeData;
        end

        function tableDeleteData(app)
            % Pull selected cells
            selectedCell = app.UITable.Selection;
            tableData = app.UITable.Data;

            if isempty(selectedCell)
                % If no cells are selected, do nothing
            else
                % Delete data in each selected cell
                for i = 1:size(selectedCell,1)
                    tableData{selectedCell(i,1),selectedCell(i,2)} = [];
                end
            end
            
            app.UITable.Data = tableData;
        end
        
        
        function tableLabel(app)
            % Labels rows and columns for table

            % See which file is selected in file list
            selectedFile = app.FileListTree.SelectedNodes.Text;

            switch selectedFile
                case 'classlist.mat'
                    % Name columns of classlist table and remove rowNames
                    app.UITable.ColumnName = {'Student Name'; 'Student ID'};
                    app.UITable.RowName = {};
                case 'answerKey.mat'
                    % Pull size of answer key table
                    [indRows, indColumns] = size(app.UITable.Data);
        
                    % Create a list of names for each row
                    rowName = cell(indRows,1);
                    rowName{1} = 'Version';
                    for i = 2:indRows
                        rowName{i} = sprintf('Question %d',i-1);
                    end
                    app.UITable.RowName = rowName;
        
                    % Create a list of names for each column
                    columnName = cell(indColumns,1);
                    columnName{1} = 'Points';
                    for i = 2:indColumns
                        columnName{i} = sprintf('Version %d',i-1);
                    end
                    app.UITable.ColumnName = columnName;
                case 'studentOutput.mat'
                    [indRows, indColumns] = size(app.UITable.Data);

                    % Create a list of names for each row
                    rowName = cell(indRows,1);
                    rowName{1} = 'Student ID';
                    if indRows >= 2
                        rowName{2} = 'Test Version';
                    end
                    for i = 3:indRows
                        rowName{i} = sprintf('Question %d',i-2);
                    end
                    app.UITable.RowName = rowName;

                    % Create a list of names for each column
                    columnName = cell(indColumns,1);
                    for i = 1:indColumns
                        columnName{i} = sprintf('Student %d',i);
                    end
                    app.UITable.ColumnName = columnName;
                case 'verifyOutput.mat'
                    [indRows, indColumns] = size(app.UITable.Data);

                    % Create a list of names for each row
                    rowName = cell(indRows,1);
                    rowName{1} = 'Student ID';
                    if indRows >= 2
                        rowName{2} = 'Test Version';
                    end
                    for i = 3:indRows
                        rowName{i} = sprintf('Question %d',i-2);
                    end
                    app.UITable.RowName = rowName;

                    % Create a list of names for each column
                    columnName = cell(indColumns,1);
                    for i = 1:indColumns
                        columnName{i} = sprintf('Student %d',i);
                    end
                    app.UITable.ColumnName = columnName;
                case 'validClasslist.mat'
                    app.UITable.ColumnName = {'Student Name'; 'Student ID'};
                    app.UITable.RowName = {};
                case 'invalidClasslist.mat'
                    app.UITable.ColumnName = {'Student Name'; 'Student ID'};
                    app.UITable.RowName = {};
                case 'answerStatistics.mat'
                    app.UITable.ColumnName = {};
                    app.UITable.RowName = {};
            end
        end
        
        function tableClean(app,direction,boolAdd)
            switch direction
                case 'rows'
                    % Remove rows with no entries
                    tableData = app.UITable.Data;
                    emptyCells = cellfun(@isempty,tableData);
                    emptyCells = min(emptyCells,[],2);
                    tableData(emptyCells,:) = []; 
        
                    % Add empty row to allow for future pasting
                    if boolAdd
                        tableData(end+1,:) = cell(1,size(tableData,2));
                    end
        
                    % Store data to table
                    app.UITable.Data = tableData;
                case 'columns'
                    % Remove rows with no entries
                    tableData = app.UITable.Data;
                    emptyCells = cellfun(@isempty,tableData);
                    emptyCells = min(emptyCells,[],1);
                    tableData(:,emptyCells) = []; 
        
                    % Add empty row to allow for future pasting
                    if boolAdd
                        tableData(:,end+1) = cell(size(tableData,1),1);
                    end
        
                    % Store data to table
                    app.UITable.Data = tableData;
            end
        end
        
        function tableFormat(app)
            % See which file is selected in file list
            selectedFile = app.FileListTree.SelectedNodes.Text;

            switch selectedFile
                case 'classlist.mat'
                    % Checks if a row has been deleted and removes it
                    tableClean(app,'rows',true);

                    % Cut table down to 2 columns
                    tableData = app.UITable.Data;
                    app.UITable.Data = tableData(:,1:2);
        
                    % Save classlist.mat
                    tableData = app.UITable.Data;
                    save(strcat(app.currentTestDirectory, '\Keys\classlist.mat'), 'tableData');
                case 'answerKey.mat'
                    % Checks if a row or column has been deleted and removes it
                    tableClean(app, 'rows', true);
                    tableClean(app, 'columns', true);
        
                    % Save answerKey.mat
                    tableData = app.UITable.Data;
                    save(strcat(app.currentTestDirectory, '\Keys\answerKey.mat'), 'tableData');
                case 'studentOutput.mat'
                    % Checks if a row has been deleted and removes it
                    tableClean(app,'rows', false);

                    % Save studentOutput.mat
                    tableData = app.UITable.Data;
                    save(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'), 'tableData');
                case 'verifyOutput.mat'
                    tableClean(app, 'rows', false);
                    tableClean(app, 'columns', false);
                case 'validClasslist.mat'
                    tableClean(app, 'rows', false);
                    tableClean(app, 'columns', false);
                case 'invalidClasslist.mat'
                    tableClean(app, 'rows', false);
                    tableClean(app, 'columns', false);
                case 'answerStatistics.mat'
                    tableClean(app, 'rows', false);
                    tableClean(app, 'columns', false);
            end

            % Make columns editable and give them column names
            app.UITable.ColumnEditable = true;
            tableLabel(app);
        end

        function generateValidateUI(app, validInd)

            if validInd <= 0
                validInd = 1;
            end
            numInvalid = size(app.invalidStudentDir, 1);
            if validInd > numInvalid
                validInd = numInvalid;
            end

            app.currentValidInd = validInd;

            studentDir = app.invalidStudentDir{validInd,2};
            app.plotValid = imshow(studentDir, 'Parent', app.ValidateAxes);

            studentInd = app.invalidStudentDir{validInd,1};
            load(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'))
            app.ValidateTable.Visible = "on";
            app.ValidateTable.Data = tableData(:,studentInd);

            [indRows, indColumns] = size(app.ValidateTable.Data);
            rowName = cell(indRows,1);
            rowName{1} = 'Student ID';
            if indRows >= 2
                rowName{2} = 'Test Version';
            end
            for i = 3:indRows
                rowName{i} = sprintf('Question %d',i-2);
            end
            app.ValidateTable.RowName = rowName;

            % Create a list of names for each column
            columnName = cell(indColumns,1);
            columnName{1} = sprintf('Student %d',studentInd);
            app.ValidateTable.ColumnName = columnName;
        end
        
  end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Restore default path
            restorePath(app)

            % Load pretrained weights
            load('AppNetwork.mat')
            app.answerNetwork = answerNet;
            app.numberNetwork = numberNet;

            % Reset file UI and clear table data
            resetFileUI(app);
            app.UITable.Data = {[],[]};

            % Disable upload answer sheet button until folder exists
            app.UploadAnswerSheetsButton.Enable = 'off';
            app.ProcessAnswerSheetsButton.Enable = 'off';
            app.ValidateAnswersButton.Enable = 'off';
            app.GenerateAnswerStatisticsButton.Enable = 'off';
            app.RepairFilesButton.Enable = 'off';
            app.DeleteFileButton.Enable = 'off';
            app.LeftValidateButton.Enable = 'off';
            app.RightValidateButton.Enable = 'off';
            app.AcceptAnswersButton.Enable = 'off';

        end

        % Button pushed function: NewSessionButton
        function NewSessionButtonPushed(app, event)
            % Callback to create new folder directory for the path

            % Open "Save As" to request destination folder from user
            [filename, pathname] = uiputfile({'*'}, 'Save as', fullfile(app.filepath));
            
            if ~(isequal(filename,0) || isequal(pathname,0))
                % User specified a file name in a folder with write permission
                app.currentTestDirectory = fullfile(pathname, filename);
                cancelSaveAs = false;
            else
                % User clicked Cancel in "Save As" dialog
                cancelSaveAs = true;
            end
            
            if ~cancelSaveAs

                % Restore the default path so there is no confusion
                restorePath(app)

                % Create folder structure
                createDirectory(app)

                % Add folder and subfolders to path
                addpath(genpath(app.currentTestDirectory));

                % Update the list of files
                fileListUpdate(app)

            else
                % User clicked Cancel in "Save As" dialog
            end
        end

        % Button pushed function: LoadSessionButton
        function LoadSessionButtonPushed(app, event)
            % Callback to set a folder directory as the path
            
            % Open "Save As" to request destination from user
            pathname = uigetdir(fullfile(app.filepath));
            
            if ~isequal(pathname,0)
                % User specified a folder with correct directory
                app.currentTestDirectory = pathname;
                cancelSaveAs = false;
            else
                % User clicked Cancel in "Save As" dialog
                cancelSaveAs = true;
            end
            
            if ~cancelSaveAs

                % Restore the default path so there is no confusion
                restorePath(app)

                % Add folder and subfolders to path
                addpath(genpath(app.currentTestDirectory));

                % Update the list of files
                fileListUpdate(app)
                
            else
                % User clicked Cancel in "Save As" dialog
            end
        end

        % Button pushed function: UploadAnswerSheetsButton
        function UploadAnswerSheetsButtonPushed(app, event)
            % Callback to choose a .pdf file to generate answer sheet .pngs 
            
            % Open the windows "open" to get folder and file
            [filename, pathname] = uigetfile({'*.pdf'}, 'Load',...
                                             fullfile(app.filepath));

            if ~(isequal(filename,0) || isequal(pathname,0))
                % User specified a file name in a folder with write permission
                cancelLoad = false;
            else
                % User clicked Cancel in "Open" dialog
                cancelLoad = true;
            end
            
            if ~cancelLoad

                % Copy pdf to input folder
                pdfFilepath = fullfile(pathname, filename);
                moveDirectory = strcat(app.currentTestDirectory,'\Input');
                pdfName = fullfile(strcat(moveDirectory,'\'),filename);
                if isfile(pdfName)
                    delete(pdfName)
                end
                copyfile(pdfFilepath,moveDirectory);

                % Convert the pdf to png images
                PDFtoImg(pdfName);

                % Update the file list to include the png files
                fileListUpdate(app)
                
            else
                % User clicked Cancel in "Save As" dialog
            end
        end

        % Selection changed function: FileListTree
        function FileListTreeSelectionChanged(app, event)
            % Checks which node has been selected and shows relevant information
            
            % Find the name of the selected node
            selectedFile = app.FileListTree.SelectedNodes.Text;

            [~, ~, fileExt] = fileparts(selectedFile);

            % Reset the shown information
            resetFileUI(app)
            
            % Check which file was selected and turn UI component on
            switch fileExt
                case '.mat'
                    % Make table visible
                    app.UITable.Visible = 'on';

                    % Load current file and put it into the table
                    load(selectedFile)
                    app.UITable.Data = tableData;

                    % Format the table
                    tableFormat(app);
                case '.png'
                    % Pull up image of selected file
                    app.plotImage = imshow(selectedFile, 'Parent', app.ImageAxes);
            end
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % Prevent matlab from overwriting new data
            app.UITable.Data(event.Indices(1), event.Indices(2)) = {event.EditData};

            tableFormat(app)
        end

        % Key press function: UITable
        function UITableKeyPress(app, event)
            % Checks for windows shortcuts and resolves logic

            switch event.Key
                % Check for paste
                case 'v'
                    if isequal(event.Modifier,{'control'})
                        % Paste data into table
                        tablePasteData(app)
                    end
                % Check for delete
                case 'delete'
                    % Delete data in table selection
                    tableDeleteData(app);
            end
            
            % Format table
            tableFormat(app)
            
        end

        % Button pushed function: DeleteFileButton
        function DeleteFileButtonPushed(app, event)
            % Delete selected node

            % Pull selected node
            selectedNode = app.FileListTree.SelectedNodes;
            
            if isempty(selectedNode)
                % No node selected
            else
                % Get directory of selected file
                directory = fileListDirectory(app,selectedNode);
    
                % Ask if they are sure they want to delete file
                question = ['Do you want to delete ',selectedNode.Text,'?'];
                answer = uiconfirm(app.ScanTechFigure,question,'Confirm Delete',...
                          "Options",["Yes","No"],...
                          "DefaultOption",2,"CancelOption",2);
            
                switch answer
                    case 'Yes'
                        % Chose to delete file
                        delete(directory);
                    case 'No'
                        % Chose to not delete file
                end
            end

            % Update the new filelist
            fileListUpdate(app)
        end

        % Button pushed function: RepairFilesButton
        function RepairFilesButtonPushed(app, event)
            % Clear selection
            app.FileListTree.SelectedNodes = [];

            % Repair files
            fileListRepair(app)

            % Update the new filelist
            fileListUpdate(app)

            % Restore the default path so there is no confusion
            restorePath(app)

            % Add folder and subfolders to path
            addpath(genpath(app.currentTestDirectory));
        end

        % Button pushed function: ProcessAnswerSheetsButton
        function ProcessAnswerSheetsButtonPushed(app, event)
            % Repair files
            fileListRepair(app)

            % Reset UI
            resetFileUI(app)

            % Read directory for compatible files
            dataDir = [app.currentTestDirectory, '\Input\'];
            filetype = '*.png';
            dirInfo = dir(strcat(dataDir,filetype));
            numFiles = size(dirInfo,1);

            % Load answer key and classlist
            load(strcat(app.currentTestDirectory, '\Keys\answerKey.mat'));
            answerKeyData = tableData;
            numQuestions = size(answerKeyData,1)-2;

            load(strcat(app.currentTestDirectory, '\Keys\classlist.mat'));
            classlistData = tableData;

            if size(answerKeyData,1) < 2 || isempty(answerKeyData{1,2})
                uialert(app.ScanTechFigure, "Fill out the Answer Key in the Keys folder", "Missing Data", "Icon", "error");
                return;
            end
            if isempty(classlistData{1,1})
                uialert(app.ScanTechFigure, "Fill out the Classlist in the Keys folder", "Missing Data", "Icon", "error");
                return;
            end

            f = waitbar(0,'Processing answer sheets');

            outputData = cell(numQuestions+2,numFiles);
            verifyData = cell(numQuestions+2,numFiles);

            warning('off','MATLAB:print:ContentTypeIgnored');
            for i = 1:numFiles

                % Process answer sheets using spatial and network information
                [studentNumberSpatial, studentNumberNetwork, studentVersion, studentAnswerSpatial, studentAnswerNetwork, textLocation, pageColor, blank] = ...
                scanAnswerSheetBackup(app, dirInfo(i).name, app.answerNetwork, app.numberNetwork, numQuestions);

                [studentNumber, studentVersion, studentAnswer, boolVerify{i}] = ...
                verifyBubbles(studentNumberSpatial, studentNumberNetwork, studentVersion, studentAnswerSpatial, studentAnswerNetwork, classlistData, answerKeyData);

                [studentAnswer] = convertStudentAnswer(studentAnswer,'num2str');

                answerSheetGraphic = createGraphic(pageColor, blank, app.ImageAxes, boolVerify{i}, studentNumber, studentVersion, studentAnswer, textLocation);
                exportgraphics(app.ImageAxes,strcat(app.currentTestDirectory,'\ScoreReports\','AnswerSheet_Student_',sprintf('%03d',i),'.png'),'ContentType','vector');
                resetFileUI(app)

                fileListUpdate(app)
    
                outputData(:,i) = [studentNumber; studentVersion; studentAnswer'];
                tableData = outputData;
                save(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'),'tableData');

                verifyAnswer = repmat({false}, numQuestions, 1);
                verifyAnswer(boolVerify{i}{3}) = {true};
                verifyData(:,i) = [boolVerify{i}(1); boolVerify{i}(2); verifyAnswer];
                tableData = verifyData;
                save(strcat(app.currentTestDirectory, '\Output\verifyOutput.mat'),'tableData');

                waitbar(i/numFiles,f,'Processing answer sheets');
            end

            close(f)
        end

        % Button pushed function: ValidateAnswersButton
        function ValidateAnswersButtonPushed(app, event)
            resetFileUI(app)
            load(strcat(app.currentTestDirectory, '\Output\verifyOutput.mat'))
            
            % Find answer sheets that are invalid
            numStudents = size(tableData,2);
            app.invalidStudentDir = cell(0,2);
            ind = 1;
            for i = 1:numStudents
                validNumber = tableData{1,i};
                validVersion = tableData{2,i};
                mask = cellfun(@(x) isequal(x,true), tableData(3:end,i));
                validAnswer = find(mask,1);
                if validNumber || validVersion || ~isempty(validAnswer)
                    app.invalidStudentDir{ind,1} = i;
                    app.invalidStudentDir{ind,2} = strcat(app.currentTestDirectory,'\ScoreReports\','AnswerSheet_Student_',sprintf('%03d',i),'.png');
                    ind = ind+1;
                end
            end

            app.LeftValidateButton.Enable = 'on';
            app.RightValidateButton.Enable = 'on';
            app.AcceptAnswersButton.Enable = 'on';
            generateValidateUI(app, 1);
            
        end

        % Button pushed function: AcceptAnswersButton
        function AcceptAnswersButtonPushed(app, event)
            % Load student data
            load(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'));

            % Store data to student data file
            studentInd = app.invalidStudentDir{app.currentValidInd,1};
            validData = app.ValidateTable.Data;
            dataSize = size(tableData,1);
            tableData(:,studentInd) = validData(1:dataSize,1);
            save(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'), 'tableData');

            % Move to next invalid ind
            if ~isempty(app.invalidStudentDir)
                generateValidateUI(app, app.currentValidInd+1);
            else
                resetFileUI(app);
            end

        end

        % Button pushed function: LeftValidateButton
        function LeftValidateButtonPushed(app, event)
            generateValidateUI(app, app.currentValidInd-1)
        end

        % Button pushed function: RightValidateButton
        function RightValidateButtonPushed(app, event)
            generateValidateUI(app, app.currentValidInd+1)
        end

        % Button pushed function: GenerateAnswerStatisticsButton
        function GenerateAnswerStatisticsButtonPushed(app, event)
            load(strcat(app.currentTestDirectory, '\Output\studentOutput.mat'))
            studentData = tableData;

            load(strcat(app.currentTestDirectory, '\Keys\answerKey.mat'));
            answerKey = tableData;

            load(strcat(app.currentTestDirectory, '\Keys\classlist.mat'));
            classlist = tableData;

            [answerStatistics, percentCorrect, validClasslist, invalidClasslist] = generateAnswerStatistics(studentData, classlist, answerKey);

            tableData = validClasslist;
            save(strcat(app.currentTestDirectory, '\Output\validClasslist.mat'), 'tableData');

            tableData = invalidClasslist;
            save(strcat(app.currentTestDirectory, '\Output\invalidClasslist.mat'), 'tableData');

            numAnswers = size(answerStatistics, 1);
            numQuestions = size(answerStatistics, 2);
            numVersions = size(answerStatistics, 3);
            formatAnswerStatistics = cell((numAnswers+2)*numVersions, numQuestions+1);

            testVersions = answerKey(1,2:end);
            ansStr = {'No Answer', 'A', 'B', 'C', 'D', 'E', 'Percent Correct'};
            questionStr = arrayfun(@(k) sprintf('Question %d', k), 1:numQuestions, 'UniformOutput', false);
            for i = 1:size(answerStatistics, 3)
                startRow = (numAnswers+2)*(i-1)+1;

                % Test verision
                formatAnswerStatistics{startRow,1} = strcat('Version ', testVersions{i});
                % Question numbers
                formatAnswerStatistics(startRow,2:end) = questionStr;
                % Answers
                formatAnswerStatistics(startRow+1:startRow+numAnswers+1,1) = ansStr';
                % Statistics
                formatAnswerStatistics(startRow+1:startRow+numAnswers,2:end) = answerStatistics(:,:,i);
                % Percent correct
                formatAnswerStatistics(startRow+numAnswers+1,2:end) = percentCorrect(i,:);
                
            end
            tableData = formatAnswerStatistics;
            save(strcat(app.currentTestDirectory, '\Output\answerStatistics.mat'), 'tableData');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ScanTechFigure = uifigure('Visible', 'off');
            app.ScanTechFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.ScanTechFigure);
            app.FileTab = uitab(app.TabGroup);
            app.FileTab.Title = 'File';
            app.ImageAxes = uiaxes(app.FileTab);
            zlabel(app.ImageAxes, 'Z')
            app.ValidateAxes = uiaxes(app.FileTab);
            zlabel(app.ValidateAxes, 'Z')
            app.FileListPanel = uipanel(app.FileTab);
            app.FileListPanel.Title = 'File List Panel';
            app.FileListTree = uitree(app.FileListPanel);
            app.FileListTree.SelectionChangedFcn = createCallbackFcn(app, @FileListTreeSelectionChanged, true);
            app.DeleteFileButton = uibutton(app.FileListPanel, 'push');
            app.DeleteFileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteFileButtonPushed, true);
            app.DeleteFileButton.Text = 'Delete File';
            app.RepairFilesButton = uibutton(app.FileListPanel, 'push');
            app.RepairFilesButton.ButtonPushedFcn = createCallbackFcn(app, @RepairFilesButtonPushed, true);
            app.RepairFilesButton.Text = 'Repair Files';
            app.InputDataPanel = uipanel(app.FileTab);
            app.InputDataPanel.Title = 'Input Data';
            app.NewSessionButton = uibutton(app.InputDataPanel, 'push');
            app.NewSessionButton.ButtonPushedFcn = createCallbackFcn(app, @NewSessionButtonPushed, true);
            app.NewSessionButton.Text = 'New Session';
            app.UploadAnswerSheetsButton = uibutton(app.InputDataPanel, 'push');
            app.UploadAnswerSheetsButton.ButtonPushedFcn = createCallbackFcn(app, @UploadAnswerSheetsButtonPushed, true);
            app.UploadAnswerSheetsButton.Text = 'Upload Answer Sheets (.pdf)';
            app.LoadSessionButton = uibutton(app.InputDataPanel, 'push');
            app.LoadSessionButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSessionButtonPushed, true);
            app.LoadSessionButton.Text = 'Load Session';
            app.ProcessDataPanel = uipanel(app.FileTab);
            app.ProcessDataPanel.Title = 'Process Data';
            app.GenerateAnswerStatisticsButton = uibutton(app.ProcessDataPanel, 'push');
            app.GenerateAnswerStatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAnswerStatisticsButtonPushed, true);
            app.GenerateAnswerStatisticsButton.Text = 'Generate Answer Statistics';
            app.ProcessAnswerSheetsButton = uibutton(app.ProcessDataPanel, 'push');
            app.ProcessAnswerSheetsButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessAnswerSheetsButtonPushed, true);
            app.ProcessAnswerSheetsButton.Text = 'Process Answer Sheets';
            app.ValidateAnswersButton = uibutton(app.ProcessDataPanel, 'push');
            app.ValidateAnswersButton.ButtonPushedFcn = createCallbackFcn(app, @ValidateAnswersButtonPushed, true);
            app.ValidateAnswersButton.Text = 'Validate Answers';
            app.UITable = uitable(app.FileTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.KeyPressFcn = createCallbackFcn(app, @UITableKeyPress, true);
            app.ValidationPanel = uipanel(app.FileTab);
            app.ValidationPanel.Title = 'Validation Panel';
            app.AcceptAnswersButton = uibutton(app.ValidationPanel, 'push');
            app.AcceptAnswersButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptAnswersButtonPushed, true);
            app.AcceptAnswersButton.Text = 'Accept Answers';
            app.RightValidateButton = uibutton(app.ValidationPanel, 'push');
            app.RightValidateButton.ButtonPushedFcn = createCallbackFcn(app, @RightValidateButtonPushed, true);
            app.RightValidateButton.Text = '→';
            app.LeftValidateButton = uibutton(app.ValidationPanel, 'push');
            app.LeftValidateButton.ButtonPushedFcn = createCallbackFcn(app, @LeftValidateButtonPushed, true);
            app.LeftValidateButton.Text = '←';
            app.ValidateTable = uitable(app.FileTab);
            app.ValidateTable.ColumnName = {'Column 1'; 'Column 2'};
            app.ValidateTable.RowName = {};
            app.ValidateTable.ColumnEditable = true;
            % Show the figure after all components are created
            app.ScanTechFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ScanTechApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ScanTechFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ScanTechFigure)
        end
    end
end